classdef audioEdito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SineorSawToothDropDown        matlab.ui.control.DropDown
        SineorSawToothDropDownLabel   matlab.ui.control.Label
        GridLayout                    matlab.ui.container.GridLayout
        EchoDelayEditField            matlab.ui.control.NumericEditField
        EchoDelayEditFieldLabel       matlab.ui.control.Label
        EchoStrengthEditField         matlab.ui.control.NumericEditField
        EchoStrengthEditFieldLabel    matlab.ui.control.Label
        SaveAs                        matlab.ui.control.Button
        StartEnd                      matlab.ui.control.RangeSlider
        CreateConstructedSoundButton  matlab.ui.control.Button
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        SampleRateEditField           matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel      matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        HzLabel_6                     matlab.ui.control.Label
        HzLabel_5                     matlab.ui.control.Label
        HzLabel_4                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        Slider_6                      matlab.ui.control.Slider
        Slider_5                      matlab.ui.control.Slider
        Slider_4                      matlab.ui.control.Slider
        Slider_3                      matlab.ui.control.Slider
        Slider_2                      matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        Label_9                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        BackwardForwardSwitch         matlab.ui.control.Switch
        VolumeSliderLabel_2           matlab.ui.control.Label
        speedSlider                   matlab.ui.control.Slider
        VolumeSlider                  matlab.ui.control.Slider
        VolumeSliderLabel             matlab.ui.control.Label
        EchoButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        fileSelect33                  matlab.ui.control.Button
        fileSelect32                  matlab.ui.control.Button
        fileSelect31                  matlab.ui.control.Button
        fileSelect21                  matlab.ui.control.Button
        fileSelect22                  matlab.ui.control.Button
        fileSelect23                  matlab.ui.control.Button
        fileSelect13                  matlab.ui.control.Button
        fileSelect12                  matlab.ui.control.Button
        fileSelect11                  matlab.ui.control.Button
        fileLoad13                    matlab.ui.control.Button
        fileLoad12                    matlab.ui.control.Button
        fileLoad23                    matlab.ui.control.Button
        fileLoad33                    matlab.ui.control.Button
        fileLoad11                    matlab.ui.control.Button
        fileLoad22                    matlab.ui.control.Button
        fileLoad21                    matlab.ui.control.Button
        fileLoad32                    matlab.ui.control.Button
        fileLoad31                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cury
        curFs
        fileTable
        curNum
        player
        protoSpeed
        protoVolume
        neoTime
        pubTime
    end
    
    methods (Access = private)
        function reset(app)
            app.VolumeSlider.Value = 100;
            app.speedSlider.Value = 1;
            app.Slider.Value = 1;
            app.Slider_2.Value = 1;
            app.Slider_3.Value = 1;
            app.Slider_4.Value = 1;
            app.Slider_5.Value = 1;
            app.Slider_6.Value = 1;
            app.protoSpeed = 1;
            app.protoVolume = 100;
            app.Label.FontColor = [0 0 0];
            app.Label_2.FontColor = [0 0 0];
            app.Label_3.FontColor = [0 0 0];
            app.Label_4.FontColor = [0 0 0];
            app.Label_5.FontColor = [0 0 0];
            app.Label_6.FontColor = [0 0 0];
            app.Label_7.FontColor = [0 0 0];
            app.Label_8.FontColor = [0 0 0];
            app.Label_9.FontColor = [0 0 0];
        end
        function colorKill(app)
            app.Label.FontColor = [0 0 0];
            app.Label_2.FontColor = [0 0 0];
            app.Label_3.FontColor = [0 0 0];
            app.Label_4.FontColor = [0 0 0];
            app.Label_5.FontColor = [0 0 0];
            app.Label_6.FontColor = [0 0 0];
            app.Label_7.FontColor = [0 0 0];
            app.Label_8.FontColor = [0 0 0];
            app.Label_9.FontColor = [0 0 0];
        end
        
        function selector(app, n)
            fileName = uigetfile({'*.wav;*.mp3;*.flac;*.m4a;*.aiff'}, 'Open an Audio File');
            %[y, fs] = audioread(fileName);
            %app.fileTable(n,:) = {fileName, fs, y};
            if fileName == 0
                fileName = "No Selection"
            end
            app.fileTable(n,1) = {fileName};
        end
        function loader(app, n)
            reset(app);
            app.curFs = 0;
            app.cury = 0;
            if ismissing(app.fileTable{n, 'Name'})
            plot(app.UIAxes, 1, 1);
            switch n
                case 1
                    colorKill(app)
                    app.Label.FontColor = [1 0 0];
                    app.Label.Text = 'Field Empty'
                case 2
                    colorKill(app)
                    app.Label_2.FontColor = [1 0 0];
                    app.Label_2.Text = 'Field Empty'
                case 3
                    colorKill(app)
                    app.Label_3.FontColor = [1 0 0];
                    app.Label_3.Text = 'Field Empty'
                case 4
                    colorKill(app)
                    app.Label_4.FontColor = [1 0 0];
                    app.Label_4.Text = 'Field Empty'
                case 5
                    app.Label_5.FontColor = [1 0 0];
                    app.Label_5.Text = 'Field Empty'
                case 6
                    colorKill(app)
                    app.Label_6.FontColor = [1 0 0];
                    app.Label_6.Text = 'Field Empty'
                case 7
                    app.Label_7.FontColor = [1 0 0];
                    app.Label_7.Text = 'Field Empty'
                case 8
                    colorKill(app)
                    app.Label_8.FontColor = [1 0 0];
                    app.Label_8.Text = 'Field Empty'
                case 9
                    colorKill(app)
                    app.Label_9.FontColor = [1 0 0];
                    app.Label_9.Text = 'Field Empty'
                otherwise
            end
            else
            [app.cury, app.curFs] = audioread(app.fileTable{n, 'Name'});
            %x = app.cury;
            %app.cury(cellfun(@(app.cury) any(isnan(app.cury)),app.cury)) = []
            %app.curFs = app.fileTable{n, 'Fs'};
            audioPlot(app);
            switch n
                case 1
                    colorKill(app)
                    app.Label.FontColor = [1 0 0];
                    app.Label.Text = app.fileTable{1, 'Name'};
                case 2
                    colorKill(app)
                    app.Label_2.FontColor = [1 0 0];
                    app.Label_2.Text = app.fileTable{2, 'Name'};
                case 3
                    colorKill(app)
                    app.Label_3.FontColor = [1 0 0];
                    app.Label_3.Text = app.fileTable{3, 'Name'};
                case 4
                    colorKill(app)
                    app.Label_4.FontColor = [1 0 0];
                    app.Label_4.Text = app.fileTable{4, 'Name'};
                case 5
                    app.Label_5.FontColor = [1 0 0];
                    app.Label_5.Text = app.fileTable{5, 'Name'};
                case 6
                    colorKill(app)
                    app.Label_6.FontColor = [1 0 0];
                    app.Label_6.Text = app.fileTable{6, 'Name'};
                case 7
                    app.Label_7.FontColor = [1 0 0];
                    app.Label_7.Text = app.fileTable{7, 'Name'};
                case 8
                    colorKill(app)
                    app.Label_8.FontColor = [1 0 0];
                    app.Label_8.Text = app.fileTable{8, 'Name'};
                case 9
                    colorKill(app)
                    app.Label_9.FontColor = [1 0 0];
                    app.Label_9.Text = app.fileTable{9, 'Name'};
                otherwise
            end
            end
        end
        function audioPlot(app)
            t = 0:1/app.curFs:(size(app.cury,1)-1)/app.curFs;
            app.StartEnd.Limits = [0 t(end)];
            app.StartEnd.Value = [0 t(end)];
            app.pubTime = t;
            t = seconds(t);
            plot(app.UIAxes, t, app.cury);
            app.player = audioplayer(app.cury, app.curFs);
            t(end)


        end
        function neoPlot(app)
            plot(app.UIAxes, app.neoTime, app.cury);
            app.player = audioplayer(app.cury, app.curFs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reset(app);
            app.curNum = 1;
            %app.fileTable = table('Size', [9, 3], 'VariableTypes', {'string', 'double', 'cell'}, 'VariableNames', {'Name', 'Fs', 'y'});
            app.fileTable = table('Size', [9, 1], 'VariableTypes', {'string'}, 'VariableNames', {'Name'});
            
        end

        % Button pushed function: fileSelect11
        function fileSelect11Pushed(app, event)
            
            selector(app, 1);
            app.Label.Text = app.fileTable{1, 'Name'};
        end

        % Button pushed function: fileSelect12
        function fileSelect12Pushed(app, event)
            selector(app, 2);
            app.Label_2.Text = app.fileTable{2, 'Name'};
        end

        % Button pushed function: fileSelect13
        function fileSelect13Pushed(app, event)
            selector(app, 3);
            app.Label_3.Text = app.fileTable{3, 'Name'};
        end

        % Button pushed function: fileSelect21
        function fileSelect21Pushed(app, event)
            selector(app, 4);
            app.Label_4.Text = app.fileTable{4, 'Name'};
        end

        % Button pushed function: fileSelect22
        function fileSelect22Pushed(app, event)
            selector(app, 5);
            app.Label_5.Text = app.fileTable{5, 'Name'};
        end

        % Button pushed function: fileSelect23
        function fileSelect23Pushed(app, event)
            selector(app, 6);
            app.Label_6.Text = app.fileTable{6, 'Name'};
        end

        % Button pushed function: fileSelect31
        function fileSelect31Pushed(app, event)
            selector(app, 7);
            app.Label_7.Text = app.fileTable{7, 'Name'};
        end

        % Button pushed function: fileSelect32
        function fileSelect32Pushed(app, event)
            selector(app, 8);
            app.Label_8.Text = app.fileTable{8, 'Name'};
        end

        % Button pushed function: fileSelect33
        function fileSelect33Pushed(app, event)
            selector(app, 9);
            app.Label_9.Text = app.fileTable{9, 'Name'};
        end

        % Button pushed function: fileLoad11
        function fileLoad11ButtonPushed(app, event)
            
            app.curNum = 1;
            loader(app, 1);

        end

        % Button pushed function: fileLoad12
        function fileLoad12ButtonPushed(app, event)
            app.curNum = 2;
            loader(app, 2);

        end

        % Button pushed function: fileLoad13
        function fileLoad13ButtonPushed(app, event)
            app.curNum = 3;
            loader(app, 3);
        end

        % Button pushed function: fileLoad21
        function fileLoad21ButtonPushed(app, event)
            app.curNum = 4;
            loader(app, 4);

        end

        % Button pushed function: fileLoad22
        function fileLoad22ButtonPushed(app, event)
            app.curNum = 5;
            loader(app, 5);
        end

        % Button pushed function: fileLoad23
        function fileLoad23ButtonPushed(app, event)
            app.curNum = 6;
            loader(app, 6);
        end

        % Button pushed function: fileLoad31
        function fileLoad31ButtonPushed(app, event)
            app.curNum = 7;
            loader(app, 7);
        end

        % Button pushed function: fileLoad32
        function fileLoad32ButtonPushed(app, event)
            app.curNum = 8;
            loader(app, 8);
        end

        % Button pushed function: fileLoad33
        function fileLoad33ButtonPushed(app, event)
            app.curNum = 9;
            loader(app, 9);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            play(app.player);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            app.cury = app.cury ./ (app.protoVolume/100);
            app.cury = app.cury .* (app.VolumeSlider.Value/100);
            app.protoVolume = app.VolumeSlider.Value;
            audioPlot(app);
        end

        % Value changed function: speedSlider
        function speedSliderValueChanged(app, event)
            app.curFs = app.curFs / app.protoSpeed;
            app.curFs = app.curFs * app.speedSlider.Value;
            app.protoSpeed = app.speedSlider.Value;
            audioPlot(app);
        end

        % Value changed function: BackwardForwardSwitch
        function BackwardForwardSwitchValueChanged(app, event)
            if app.BackwardForwardSwitch.Value == "Forward"
                app.cury = flipud(app.cury);
            else
                app.cury = flipud(app.cury)
            end
            audioPlot(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            logVal = (1./app.cury > 64 & 1./app.cury < 128) %| (1./app.cury < -64 & 1./app.cury > -128)
            app.cury(logVal) = app.cury(logVal) * app.Slider.Value;
            audioPlot(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            logVal = (1./app.cury > 129 & 1./app.cury < 256) %| (1./app.cury < -129 & 1./app.cury > -256)
            app.cury(logVal) = app.cury(logVal) * app.Slider.Value;
            audioPlot(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            logVal = (1./app.cury > 257 & 1./app.cury < 512) %| (1./app.cury < -257 & 1./app.cury > -512)
            app.cury(logVal) = app.cury(logVal) * app.Slider.Value;
            audioPlot(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            logVal = (1./app.cury > 513 & 1./app.cury < 1024) %| (1./app.cury < -513 & 1./app.cury > -1024)
            app.cury(logVal) = app.cury(logVal) * app.Slider.Value;
            audioPlot(app);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            logVal = (1./app.cury > 1025 & 1./app.cury < 2048) %| (1./app.cury < -1025 & 1./app.cury > -2048)
            app.cury(logVal) = app.cury(logVal) * app.Slider.Value;
            audioPlot(app);
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)

        end

        % Callback function
        function SavePushed(app, event)
            audiowrite(app.fileTable{app.curNum, 'Name'}, app.cury, app.curFs)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            
        end

        % Value changed function: SampleRateEditField
        function SampleRateEditFieldValueChanged(app, event)
            value = app.SampleRateEditField.Value;
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
            
        end

        % Value changed function: SineorSawToothDropDown
        function SineorSawToothDropDownValueChanged(app, event)
            value = app.SineorSawToothDropDown.Value;
            
        end

        % Button pushed function: CreateConstructedSoundButton
        function CreateConstructedSoundButtonPushed(app, event)
            if app.SineorSawToothDropDown.Value == "Sine"
                t = linspace(0, app.DurationEditField.Value, app.DurationEditField.Value * app.SampleRateEditField.Value);
                app.cury = app.AmplitudeEditField.Value * sin(2*pi*t*app.FrequencyEditField.Value);
                app.curFs = app.SampleRateEditField.Value;
                app.neoTime = t;
                neoPlot(app);
                play(app.player);
                audiowrite('ConstructedSineWave.wav', app.cury, app.curFs);
                app.fileTable(app.curNum, 1) = {'ConstructedSineWave.wav'};
                loader(app, app.curNum);
            else
                t = linspace(0, app.DurationEditField.Value, app.DurationEditField.Value * app.SampleRateEditField.Value);
                app.cury = app.AmplitudeEditField.Value * sawtooth(2*pi*t*app.FrequencyEditField.Value);
                app.curFs = app.SampleRateEditField.Value;
                app.neoTime = t;
                neoPlot(app);
                play(app.player);
                audiowrite('ConstructedSawtoothWave.wav', app.cury, app.curFs);
                app.fileTable(app.curNum, 1) = {'ConstructedSawtoothWave.wav'};
                loader(app, app.curNum);
            end
        end

        % Value changed function: StartEnd
        function StartEndValueChanged(app, event)
            value = app.StartEnd.Value;
            app.cury(1:app.StartEnd.Value(1)*app.curFs,:) = []
            app.cury(app.StartEnd.Value(2)*app.curFs:end,:) = []
            audioPlot(app);
        end

        % Button pushed function: SaveAs
        function SaveAsButtonPushed(app, event)
            audiowrite(string(inputdlg("Enter File Name")),app.cury, app.curFs);
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)
            echo = app.cury;
            echo((1+end - (app.EchoDelayEditField.Value*app.curFs)):end,:) = [];
            echo = [zeros(app.EchoDelayEditField.Value*app.curFs, 2); echo];
            echo = echo .* app.EchoStrengthEditField.Value;
            
            size(app.cury)
            size(echo)
            app.cury = app.cury + echo;
            audioPlot(app);
            
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Audio Graph')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pitch')
            zlabel(app.UIAxes, 'Z')
            app.fileLoad31 = uibutton(app.GridLayout, 'push');
            app.fileLoad31.ButtonPushedFcn = createCallbackFcn(app, @fileLoad31ButtonPushed, true);
            app.fileLoad31.Text = 'Load 7';
            app.fileLoad32 = uibutton(app.GridLayout, 'push');
            app.fileLoad32.ButtonPushedFcn = createCallbackFcn(app, @fileLoad32ButtonPushed, true);
            app.fileLoad32.Text = 'Load 8';
            app.fileLoad21 = uibutton(app.GridLayout, 'push');
            app.fileLoad21.ButtonPushedFcn = createCallbackFcn(app, @fileLoad21ButtonPushed, true);
            app.fileLoad21.Text = 'Load 4';
            app.fileLoad22 = uibutton(app.GridLayout, 'push');
            app.fileLoad22.ButtonPushedFcn = createCallbackFcn(app, @fileLoad22ButtonPushed, true);
            app.fileLoad22.Text = 'Load 5';
            app.fileLoad11 = uibutton(app.GridLayout, 'push');
            app.fileLoad11.ButtonPushedFcn = createCallbackFcn(app, @fileLoad11ButtonPushed, true);
            app.fileLoad11.Text = 'Load 1';
            app.fileLoad33 = uibutton(app.GridLayout, 'push');
            app.fileLoad33.ButtonPushedFcn = createCallbackFcn(app, @fileLoad33ButtonPushed, true);
            app.fileLoad33.Text = 'Load 9';
            app.fileLoad23 = uibutton(app.GridLayout, 'push');
            app.fileLoad23.ButtonPushedFcn = createCallbackFcn(app, @fileLoad23ButtonPushed, true);
            app.fileLoad23.Text = 'Load 6';
            app.fileLoad12 = uibutton(app.GridLayout, 'push');
            app.fileLoad12.ButtonPushedFcn = createCallbackFcn(app, @fileLoad12ButtonPushed, true);
            app.fileLoad12.Text = 'Load 2';
            app.fileLoad13 = uibutton(app.GridLayout, 'push');
            app.fileLoad13.ButtonPushedFcn = createCallbackFcn(app, @fileLoad13ButtonPushed, true);
            app.fileLoad13.Text = 'Load 3';
            app.fileSelect11 = uibutton(app.GridLayout, 'push');
            app.fileSelect11.ButtonPushedFcn = createCallbackFcn(app, @fileSelect11Pushed, true);
            app.fileSelect11.Text = 'Select File';
            app.fileSelect12 = uibutton(app.GridLayout, 'push');
            app.fileSelect12.ButtonPushedFcn = createCallbackFcn(app, @fileSelect12Pushed, true);
            app.fileSelect12.Text = 'Select File';
            app.fileSelect13 = uibutton(app.GridLayout, 'push');
            app.fileSelect13.ButtonPushedFcn = createCallbackFcn(app, @fileSelect13Pushed, true);
            app.fileSelect13.Text = 'Select File';
            app.fileSelect23 = uibutton(app.GridLayout, 'push');
            app.fileSelect23.ButtonPushedFcn = createCallbackFcn(app, @fileSelect23Pushed, true);
            app.fileSelect23.Text = 'Select File';
            app.fileSelect22 = uibutton(app.GridLayout, 'push');
            app.fileSelect22.ButtonPushedFcn = createCallbackFcn(app, @fileSelect22Pushed, true);
            app.fileSelect22.Text = 'Select File';
            app.fileSelect21 = uibutton(app.GridLayout, 'push');
            app.fileSelect21.ButtonPushedFcn = createCallbackFcn(app, @fileSelect21Pushed, true);
            app.fileSelect21.Text = 'Select File';
            app.fileSelect31 = uibutton(app.GridLayout, 'push');
            app.fileSelect31.ButtonPushedFcn = createCallbackFcn(app, @fileSelect31Pushed, true);
            app.fileSelect31.Text = 'Select File';
            app.fileSelect32 = uibutton(app.GridLayout, 'push');
            app.fileSelect32.ButtonPushedFcn = createCallbackFcn(app, @fileSelect32Pushed, true);
            app.fileSelect32.Text = 'Select File';
            app.fileSelect33 = uibutton(app.GridLayout, 'push');
            app.fileSelect33.ButtonPushedFcn = createCallbackFcn(app, @fileSelect33Pushed, true);
            app.fileSelect33.Text = 'Select File';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'pause.png');
            app.PauseButton.Text = 'Pause';
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'stop-button (1).png');
            app.StopButton.Text = 'Stop';
            app.EchoButton = uibutton(app.GridLayout, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.VolumeSliderLabel = uilabel(app.GridLayout);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.GridLayout);
            app.VolumeSlider.MajorTicks = [0 25 50 75 100];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.VolumeSlider.Value = 100;
            app.speedSlider = uislider(app.GridLayout);
            app.speedSlider.Limits = [0.25 2];
            app.speedSlider.MajorTicks = [0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.speedSlider.ValueChangedFcn = createCallbackFcn(app, @speedSliderValueChanged, true);
            app.speedSlider.MinorTicks = [0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2];
            app.speedSlider.Value = 1;
            app.VolumeSliderLabel_2 = uilabel(app.GridLayout);
            app.VolumeSliderLabel_2.Text = 'Speed';
            app.BackwardForwardSwitch = uiswitch(app.GridLayout, 'slider');
            app.BackwardForwardSwitch.Items = {'Back', 'Forward'};
            app.BackwardForwardSwitch.ValueChangedFcn = createCallbackFcn(app, @BackwardForwardSwitchValueChanged, true);
            app.BackwardForwardSwitch.Value = 'Forward';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 8.71229556275367;
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = '64 Hz';
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.Limits = [0 20];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.Limits = [0 20];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.Limits = [0 20];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.Limits = [0 20];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_6 = uislider(app.GridLayout);
            app.Slider_6.Limits = [0 20];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '128 Hz';
            app.HzLabel_3 = uilabel(app.GridLayout);
            app.HzLabel_3.Text = '256 Hz';
            app.HzLabel_4 = uilabel(app.GridLayout);
            app.HzLabel_4.Text = '512 Hz';
            app.HzLabel_5 = uilabel(app.GridLayout);
            app.HzLabel_5.Text = '1024 Hz';
            app.HzLabel_6 = uilabel(app.GridLayout);
            app.HzLabel_6.Text = '2028 Hz';
            app.AmplitudeEditFieldLabel = uilabel(app.GridLayout);
            app.AmplitudeEditFieldLabel.Text = ' Amplitude';
            app.AmplitudeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.GridLayout);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.GridLayout, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.SampleRateEditFieldLabel = uilabel(app.GridLayout);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateEditFieldValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.CreateConstructedSoundButton = uibutton(app.GridLayout, 'push');
            app.CreateConstructedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @CreateConstructedSoundButtonPushed, true);
            app.CreateConstructedSoundButton.Text = 'Create Constructed Sound';
            app.StartEnd = uislider(app.GridLayout, 'range');
            app.StartEnd.Limits = [0 1];
            app.StartEnd.ValueChangedFcn = createCallbackFcn(app, @StartEndValueChanged, true);
            app.StartEnd.Value = [0 0];
            app.SaveAs = uibutton(app.GridLayout, 'push');
            app.SaveAs.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAs.Text = 'Save As';
            app.EchoStrengthEditFieldLabel = uilabel(app.GridLayout);
            app.EchoStrengthEditFieldLabel.Text = 'Echo Strength';
            app.EchoStrengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.EchoStrengthEditField.Value = 0.5;
            app.EchoDelayEditFieldLabel = uilabel(app.GridLayout);
            app.EchoDelayEditFieldLabel.Text = 'Echo Delay';
            app.EchoDelayEditField = uieditfield(app.GridLayout, 'numeric');
            app.EchoDelayEditField.Value = 0.5;
            app.SineorSawToothDropDownLabel = uilabel(app.UIFigure);
            app.SineorSawToothDropDownLabel.Text = 'Sine or Saw Tooth';
            app.SineorSawToothDropDown = uidropdown(app.UIFigure);
            app.SineorSawToothDropDown.Items = {'Saw Tooth', 'Sine'};
            app.SineorSawToothDropDown.ValueChangedFcn = createCallbackFcn(app, @SineorSawToothDropDownValueChanged, true);
            app.SineorSawToothDropDown.Value = 'Sine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioEdito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end